classdef PVModuleSimulatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenjsonfileMenu               matlab.ui.container.Menu
        SaveconfigasMenu               matlab.ui.container.Menu
        ClosejsonfileMenu              matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AuthorLoganathanAvithiNagarajanMenu  matlab.ui.container.Menu
        Contactanloganathan20gmailcomMenu  matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        xyzaspectDropDown              matlab.ui.control.DropDown
        xyzaspectDropDownLabel         matlab.ui.control.Label
        SelectComponentDropDown        matlab.ui.control.DropDown
        SelectComponentDropDownLabel   matlab.ui.control.Label
        InterlayerGapcmSlider          matlab.ui.control.Slider
        InterlayerGapcmSliderLabel     matlab.ui.control.Label
        SelectViewDropDown             matlab.ui.control.DropDown
        SelectViewDropDownLabel        matlab.ui.control.Label
        ModuleConfigurationPanel       matlab.ui.container.Panel
        AdvancedOptionsPanel           matlab.ui.container.Panel
        NoteXandYaxesareinmetersmwhiletheZaxisisincentimeterscmLabel  matlab.ui.control.Label
        MadebyLoganathanAvithiNagarajananloganathan20gmailcomLabel  matlab.ui.control.Label
        ResettodefaultvaluesButton     matlab.ui.control.Button
        HeightcmEditField_2            matlab.ui.control.NumericEditField
        HeightcmEditField_2Label       matlab.ui.control.Label
        JunctionBoxPanel               matlab.ui.container.Panel
        DepthmmEditField               matlab.ui.control.NumericEditField
        DepthmmEditFieldLabel          matlab.ui.control.Label
        ColorColorPicker_4             matlab.ui.control.ColorPicker
        ColorColorPicker_4Label        matlab.ui.control.Label
        WidthcmEditField_2             matlab.ui.control.NumericEditField
        WidthcmEditField_2Label        matlab.ui.control.Label
        BackSheetPanel                 matlab.ui.container.Panel
        ThicknessmmEditField_5         matlab.ui.control.NumericEditField
        ThicknessmmEditField_5Label    matlab.ui.control.Label
        ColorColorPicker_2             matlab.ui.control.ColorPicker
        ColorColorPicker_2Label        matlab.ui.control.Label
        FramePanel                     matlab.ui.container.Panel
        WidthofFrameEmmEditField       matlab.ui.control.NumericEditField
        WidthofFrameEmmEditFieldLabel  matlab.ui.control.Label
        ColorColorPicker_3             matlab.ui.control.ColorPicker
        ColorColorPicker_3Label        matlab.ui.control.Label
        ThicknessmmEditField           matlab.ui.control.NumericEditField
        ThicknessmmEditFieldLabel      matlab.ui.control.Label
        GlassCoverPanel                matlab.ui.container.Panel
        ThicknessmmEditField_3         matlab.ui.control.NumericEditField
        ThicknessmmEditField_3Label    matlab.ui.control.Label
        ModuleTypeDropDown             matlab.ui.control.DropDown
        ModuleTypeDropDownLabel        matlab.ui.control.Label
        EncapsulantPanel               matlab.ui.container.Panel
        ThicknessmmEditField_2         matlab.ui.control.NumericEditField
        ThicknessmmEditField_2Label    matlab.ui.control.Label
        RearPanel                      matlab.ui.container.Panel
        ThicknessmmEditField_4         matlab.ui.control.NumericEditField
        ThicknessmmEditField_4Label    matlab.ui.control.Label
        TypeDropDown_2                 matlab.ui.control.DropDown
        TypeDropDown_2Label            matlab.ui.control.Label
        FrontPanel                     matlab.ui.container.Panel
        TypeDropDown                   matlab.ui.control.DropDown
        TypefrontLabel                 matlab.ui.control.Label
        SolarCellsPanel                matlab.ui.container.Panel
        ColorColorPicker               matlab.ui.control.ColorPicker
        ColorColorPickerLabel          matlab.ui.control.Label
        CellGapcmEditField             matlab.ui.control.NumericEditField
        CellGapcmLabel                 matlab.ui.control.Label
        HeightcmEditField              matlab.ui.control.NumericEditField
        HeightcmEditFieldLabel         matlab.ui.control.Label
        ThicknessmEditField            matlab.ui.control.NumericEditField
        ThicknessmLabel                matlab.ui.control.Label
        WidthcmEditField               matlab.ui.control.NumericEditField
        WidthcmLabel                   matlab.ui.control.Label
        ColumnsSpinner                 matlab.ui.control.Spinner
        ColumnsSpinnerLabel            matlab.ui.control.Label
        RowsSpinner                    matlab.ui.control.Spinner
        RowsSpinnerLabel               matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        infoTab                        matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
        JunctionBoxTextArea            matlab.ui.control.TextArea
        JunctionBoxTextAreaLabel       matlab.ui.control.Label
        FrameTextArea                  matlab.ui.control.TextArea
        FrameTextAreaLabel             matlab.ui.control.Label
        EncapsulantTextArea            matlab.ui.control.TextArea
        EncapsulantTextAreaLabel       matlab.ui.control.Label
        BacksheetTextArea              matlab.ui.control.TextArea
        BacksheetLabel                 matlab.ui.control.Label
        GlassCoverTextArea             matlab.ui.control.TextArea
        GlassCoverTextAreaLabel        matlab.ui.control.Label
        SolarcellsTextArea             matlab.ui.control.TextArea
        SolarcellsTextAreaLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Default values ----------------------
        defaultSolarModuleType = "Monofacial"

        defaultSolarCellThickness_microm = 200
        defaultNSolarCellRows = 15
        defaultNSolarCellCols = 10
        defaultSolarCellWidth_cm = 15
        defaultSolarCellHeight_cm = 15
        defaultSolarCellGap_cm = 1.5
        defaultSolarCellColor (3,1) double = [0, 0.45, 0.74]
        
        defaultGlassCoverThickness_mm = 3
        defaultGlassColor (3,1) double = [1, 1, 1]
        defaultGlassColorAlpha = 0.1

        defaultBackSheetThickness_mm = 5
        defaultBackSheetColor (3,1) double = [1, 1, 1]

        defaultFrontEncapsulantThickness_mm = 0.5
        defaultRearEncapsulantThickness_mm = 0.5
        defaultFrontEncapsulantType = "EVA"
        defaultRearEncapsulantType = "EVA"
        defaultEncapsulantColor (3,1) double = [0.54 0.17 0.89]
        defaultEncapsulantColorAlpha = 0.2
        
        defaultFrameThickness_mm = 3
        defaultFrameEWidth_mm = 10 
        defaultFrameLowerEHeight_mm = 10 
        defaultFrameMaterial = "Aluminium"
        defaultFrameColor (3,1) double = [0.8, 0.8, 0.8]

        defaultJunctionBoxColor (3,1) double = [0, 0, 0]
        defaultJunctionBoxWidth_cm = 10 
        defaultJunctionBoxHeight_cm = 10 
        defaultJunctionBoxThickness_mm = 20

        defaultViewType = "Default 3D"
        defaultInterLayerGap = 0
        defaultSelectComponent = "All"

        defaultViewFrame = true
        defaultViewGlass = true
        defaultViewSolarCells = true
        defaultViewEncapsulant = true
        defaultViewBackSheet = true
        defaultViewJunctionBox = true

        defaultViewLeftFrame = true
        defaultViewRightFrame = true
        defaultViewTopFrame = true
        defaultViewBottomFrame = true

        default_x_y_z_aspect = "1, 1, 0.25"

        defaultWindow = "Home";
        defaultFileName = ""
        defaultPath = ""

        %  ------------------------------------


        solarModuleType = "Monofacial"

        solarCellThickness_microm = 200
        nSolarCellRows = 15
        nSolarCellCols = 10
        solarCellWidth_cm = 15
        solarCellHeight_cm = 15
        solarCellGap_cm = 1.5
        solarCellColor (3,1) double = [0, 0.45, 0.74]
        
        glassCoverThickness_mm = 3
        glassColor (3,1) double = [1, 1, 1]
        glassColorAlpha = 0.1

        backSheetThickness_mm = 5
        backSheetColor (3,1) double = [1, 1, 1]

        frontEncapsulantThickness_mm = 0.5
        rearEncapsulantThickness_mm = 0.5
        frontEncapsulantType = "EVA"
        rearEncapsulantType = "EVA"
        encapsulantColor (3,1) double = [0.54 0.17 0.89]
        encapsulantColorAlpha = 0.2
        
        frameThickness_mm = 3
        frameEWidth_mm = 10 
        frameLowerEHeight_mm = 10 
        frameMaterial = "Aluminium"
        frameColor (3,1) double = [0.8, 0.8, 0.8]

        junctionBoxColor (3,1) double = [0, 0, 0]
        junctionBoxWidth_cm = 10 
        junctionBoxHeight_cm = 10 
        junctionBoxThickness_mm = 20

        viewType = "Default 3D"
        interLayerGap = 0
        selectComponent = "All"

        viewFrame = true
        viewGlass = true
        viewSolarCells = true
        viewEncapsulant = true
        viewBackSheet = true
        viewJunctionBox = true
        
        viewLeftFrame = true
        viewRightFrame = true
        viewTopFrame = true
        viewBottomFrame = true

        x_y_z_aspect = "1, 1, 0.25"

        window = "Home";
        fileName = ""
        path = ""
    end
    
    properties (Dependent)
        solarCellThickness
        solarCellWidth 
        solarCellHeight 
        solarCellGap 
        
        glassCoverThickness 
    
        backSheetThickness

        frontEncapsulantThickness 
        rearEncapsulantThickness 
        
        frameThickness  % along z axis (in cm)
        frameThicknessAlongXY  % in m
        frameEWidth 
        frameLowerEHeight

        junctionBoxWidth
        junctionBoxHeight 
        junctionBoxThickness
    end
    
    methods

        function val = get.solarCellThickness(app)
            % µm to cm, since z axis is in cm
            val = app.solarCellThickness_microm * 10^-4;  
        end
        
        function val = get.solarCellWidth(app)
            % cm to m, since both x and y axes are in m
            val = app.solarCellWidth_cm * 10^-2;
        end

        function val = get.solarCellHeight(app)
            % cm to m, since both x and y axes are in m
            val = app.solarCellHeight_cm * 10^-2;
        end

        function val = get.solarCellGap(app)
             % cm to m, since both x and y axes are in m
            val = app.solarCellGap_cm * 10^-2;
        end

        function val = get.glassCoverThickness(app)
             % mm to cm, since z axis is in cm
            val = app.glassCoverThickness_mm * 10^-1;
        end

        function val = get.backSheetThickness(app)
            % mm to cm, since z axis is in cm
            val = app.backSheetThickness_mm * 10^-1;
        end

        function val = get.frontEncapsulantThickness(app)
            % mm to cm, since z axis is in cm
            val = app.frontEncapsulantThickness_mm * 10^-1;
        end

        function val = get.rearEncapsulantThickness(app)
            % mm to cm, since z axis is in cm
            val = app.rearEncapsulantThickness_mm * 10^-1;
        end

        function val = get.frameThickness(app)
            % mm to cm, since z axis is in cm
            val = app.frameThickness_mm * 10^-1;
        end

        function val = get.frameThicknessAlongXY(app)
             % mm to m, since x and y axes are in m
            val = app.frameThickness_mm * 10^-3;
        end
        
        function val = get.frameEWidth(app)
            % mm to m, since x and y axes are in m
            val = app.frameEWidth_mm * 10^-3;
        end
        
        function val = get.frameLowerEHeight(app)
            % mm to cm, since z axis is in cm
            val = app.frameLowerEHeight_mm * 10^-1;
        end

        function val = get.junctionBoxWidth(app)
             % cm to m, since both x and y axes are in m
            val = app.junctionBoxWidth_cm * 10^-2;
        end

        function val = get.junctionBoxHeight(app)
            % cm to m, since both x and y axes are in m
            val = app.junctionBoxHeight_cm * 10^-2;
        end

        function val = get.junctionBoxThickness(app)
              % mm to cm, since z axis is in cm
            val = app.junctionBoxThickness_mm * 10^-1;
        end

    end

    methods (Access = private)

        function PlotPVModule(app)
            ValidatePVModuleParameters(app)
            initPlot(app);
            
            zOffset = 0; % in cm
        
            % Plot Layers
            PlotGlass(app, zOffset, true);
            zOffset = zOffset + app.glassCoverThickness + app.interLayerGap;

            PlotEncapsulant(app, zOffset, true);
            zOffset = zOffset + app.frontEncapsulantThickness + app.interLayerGap;

            PlotCells(app, zOffset); 
            zOffset = zOffset + app.solarCellThickness + app.interLayerGap;

            PlotEncapsulant(app, zOffset, false);
            zOffset = zOffset + app.rearEncapsulantThickness + app.interLayerGap;

            PlotBackSheet(app, zOffset); % activates only for monofacial
            if strcmp(app.solarModuleType, "Monofacial")
                zOffset = zOffset + app.backSheetThickness;
            end
            
            PlotGlass(app, zOffset, false); % activates only for bifacial solar module
            if strcmp(app.solarModuleType, "Bifacial")
                zOffset = zOffset + app.glassCoverThickness;
            end
            PlotEFrame(app, zOffset); % activates only for monofacial

            PlotJunctionBox(app, zOffset)
              
        end

        function PlotCells(app, zOffset)
            if app.viewSolarCells
                for i = 1:app.nSolarCellCols
                    for j = 1:app.nSolarCellRows
                        % front bottom left position point of the current solar
                        % cell cuboid surface
                        px = (i-1) * (app.solarCellWidth + app.solarCellGap);
                        py = (j-1) * (app.solarCellHeight + app.solarCellGap);
                        point = [px, py, zOffset];
                        
                        % dimensions of the surface in each direction from the
                        % point
                        dx = app.solarCellWidth;
                        dy = app.solarCellHeight;
                        dz = app.solarCellThickness;
                        dimensions = [dx, dy, dz];
    
                        % get cuboid surface data 
                        [x, y, z] = cuboidData(app, point, dimensions);
     
                        % plot the cuboid
                        SurfPlot(app, x, y, z, app.solarCellColor);
                    end
                end
            end
        end
        
        function PlotGlass(app, zOffset, isFront)
            if strcmp(app.solarModuleType, "Bifacial")
                ViewPlotGlassUtil(app, zOffset);
            else
                if isFront
                    ViewPlotGlassUtil(app, zOffset);
                end
            end
        end

        function ViewPlotGlassUtil(app, zOffset)
             if app.viewGlass
                % front bottom left point of the glass cuboid surface 
                px = -app.solarCellGap-app.frameEWidth;
                py = -app.solarCellGap-app.frameEWidth;
                pz = zOffset;
                point = [px, py, pz];
    
                % dimensions of the surface in each direction from the point
                dx = app.nSolarCellCols * app.solarCellWidth ...
                    + (app.nSolarCellCols -1) * app.solarCellGap ...
                    + 2 * app.solarCellGap + 2 * app.frameEWidth;
                dy = app.nSolarCellRows * app.solarCellHeight ...
                    + (app.nSolarCellRows -1) * app.solarCellGap ...
                    + 2 * app.solarCellGap + 2 * app.frameEWidth;
                dz = app.glassCoverThickness;
                dimensions = [dx, dy, dz];
    
                % get cuboid surface data 
                [x, y, z] = cuboidData(app, point, dimensions); 
    
                % plot the cuboid
                SurfPlot(app, x, y, z, app.glassColor, app.glassColorAlpha);
             end
        end


        function PlotEncapsulant(app, zOffset, isFront)
            if app.viewEncapsulant
                % front bottom left point of the encapsulant cuboid surface
                px = -app.solarCellGap-app.frameEWidth;
                py = -app.solarCellGap-app.frameEWidth;
                pz = zOffset;
                point = [px, py, pz];
    
                % dimensions of the surface in each direction from the position
                % point
                dx = app.nSolarCellCols * app.solarCellWidth ...
                    + (app.nSolarCellCols -1) * app.solarCellGap ...
                    + 2 * app.solarCellGap + 2 * app.frameEWidth;
                dy = app.nSolarCellRows * app.solarCellHeight ...
                    + (app.nSolarCellRows -1) * app.solarCellGap ...
                    + 2 * app.solarCellGap + 2 * app.frameEWidth;
                if isFront
                    dz = app.frontEncapsulantThickness;
                else
                    dz = app.rearEncapsulantThickness;
                end
                dimensions = [dx, dy, dz];
                
                % get cuboid surface data 
                [x, y, z] = cuboidData(app, point, dimensions);
    
                % plot the cuboid
                SurfPlot(app, x, y, z, app.encapsulantColor, app.encapsulantColorAlpha);
             end
         end
         
         function PlotBackSheet(app, zOffset)
            if strcmp(app.solarModuleType, "Monofacial") && app.viewBackSheet
                % front bottom left point of the backsheet cuboid surface
                px = -app.solarCellGap-app.frameEWidth;
                py = -app.solarCellGap-app.frameEWidth;
                pz = zOffset;
                point = [px, py, pz];
                % dimensions of the surface in each direction from the position
                % point
                dx = app.nSolarCellCols * app.solarCellWidth ...
                    + (app.nSolarCellCols -1) * app.solarCellGap ...
                    + 2 * app.solarCellGap + 2 * app.frameEWidth;
                dy = app.nSolarCellRows * app.solarCellHeight ...
                    + (app.nSolarCellRows -1) * app.solarCellGap ...
                    + 2 * app.solarCellGap + 2 * app.frameEWidth;
                dz = app.backSheetThickness;
                dimensions = [dx, dy, dz];
    
                % get cuboid surface data 
                [x, y, z] = cuboidData(app, point, dimensions);
    
                % plot the cuboid
                SurfPlot(app, x, y, z, app.backSheetColor);
            end
         end
        
         function PlotEFrame(app, zOffset)
            if strcmp(app.solarModuleType, "Monofacial") && app.viewFrame
                if app.viewLeftFrame
                    % front bottom left point on the front e-width-left cuboid
                    % surface
                    px = -app.solarCellGap-app.frameEWidth;
                    py = -app.solarCellGap-app.frameEWidth;
                    pz = -app.frameThickness;
                    point = [px, py, pz];
        
                    % dimensions of the surface in each direction from the position
                    % point
                    dx = app.frameEWidth;
                    dy = app.nSolarCellRows * app.solarCellHeight ...
                        + (app.nSolarCellRows - 1) * app.solarCellGap ...
                        + 2 * app.solarCellGap + 2 * app.frameEWidth;
                    dz = app.frameThickness;
                    dimensions = [dx, dy, dz];
        
                    % get cuboid surface data 
                    [x, y, z] = cuboidData(app, point, dimensions);
        
                    % plot the cuboid
                    SurfPlot(app, x, y, z, app.frameColor);
    

                    % front bottom left point on the rear e-width-left cuboid
                    % surface
                    px = -app.solarCellGap-app.frameEWidth;
                    py = -app.solarCellGap-app.frameEWidth;
                    pz = zOffset;
                    point = [px, py, pz];
        
                    % dimensions of the surface in each direction from the position
                    % point
                    dx = app.frameEWidth;
                    dy = (app.nSolarCellRows * app.solarCellHeight) ...
                        + (app.nSolarCellRows - 1) * app.solarCellGap ...
                        + 2 * app.solarCellGap + 2 * app.frameEWidth; 
                    dz = app.frameThickness;
                    dimensions = [dx, dy, dz];
        
                    % get cuboid surface data 
                    [x, y, z] = cuboidData(app, point, dimensions);
        
                    % plot the cuboid
                    SurfPlot(app, x, y, z, app.frameColor);


                    % front bottom left point on the rear's last e-tooth 
                    % e-width-left cuboid surface
                    px = -app.solarCellGap-app.frameEWidth;
                    py = -app.solarCellGap-app.frameEWidth;
                    pz = zOffset + app.frameLowerEHeight;
                    point = [px, py, pz];
        
                    % dimensions of the surface in each direction from the position
                    % point
                    dx = app.frameEWidth;
                    dy = (app.nSolarCellRows * app.solarCellHeight) ...
                        + (app.nSolarCellRows - 1) * app.solarCellGap ...
                        + 2 * app.solarCellGap + 2 * app.frameEWidth;
                    dz = app.frameThickness;
                    dimensions = [dx, dy, dz];
        
                    % get cuboid surface data 
                    [x, y, z] = cuboidData(app, point, dimensions);
        
                    % plot the cuboid
                    SurfPlot(app, x, y, z, app.frameColor);


                    % front bottom left point on the left cuboid frame wall
                    px = -app.solarCellGap-app.frameEWidth-app.frameThicknessAlongXY;
                    py = -app.solarCellGap-app.frameEWidth-app.frameThicknessAlongXY;
                    pz = -app.frameThickness;
                    point = [px, py, pz];
        
                    % dimensions of the surface in each direction from the position
                    % point
                    dx = app.frameThicknessAlongXY;
                    dy = (app.nSolarCellRows * app.solarCellHeight) ...
                        + (app.nSolarCellRows - 1) * app.solarCellGap ...
                        + 2 * app.solarCellGap + 2 * app.frameThicknessAlongXY ...
                        + 2 * app.frameEWidth;
                    dz = zOffset + 2 * app.frameThickness + app.frameLowerEHeight;
                    dimensions = [dx, dy, dz];
        
                    % get cuboid surface data 
                    [x, y, z] = cuboidData(app, point, dimensions);
        
                    % plot the cuboid
                    SurfPlot(app, x, y, z, app.frameColor);
                end

                if app.viewRightFrame
    
                    % front bottom left point on the front e-width-right cuboid
                    % surface
                    px = app.nSolarCellCols * app.solarCellWidth ...
                        + (app.nSolarCellCols - 1) * app.solarCellGap ...
                        + app.solarCellGap;
                    py = -app.solarCellGap - app.frameEWidth;
                    pz = -app.frameThickness;
                    point = [px, py, pz];
        
                    % dimensions of the surface in each direction from the position
                    % point
                    dx = app.frameEWidth;
                    dy = (app.nSolarCellRows * app.solarCellHeight) ...
                        + (app.nSolarCellRows - 1) * app.solarCellGap ...
                        + 2 * app.solarCellGap + 2 * app.frameEWidth;
                    dz = app.frameThickness;
                    dimensions = [dx, dy, dz];
        
                    % get cuboid surface data 
                    [x, y, z] = cuboidData(app, point, dimensions);
        
                    % plot the cuboid
                    SurfPlot(app, x, y, z, app.frameColor);


                    % front bottom left point on the rear e-width-right cuboid
                    % surface
                    px = app.nSolarCellCols * app.solarCellWidth ...
                        + (app.nSolarCellCols - 1) * app.solarCellGap ...
                        + app.solarCellGap;
                    py = -app.solarCellGap - app.frameEWidth;
                    pz = zOffset;
                    point = [px, py, pz];
        
                    % dimensions of the surface in each direction from the position
                    % point
                    dx = app.frameEWidth;
                    dy = (app.nSolarCellRows * app.solarCellHeight) ...
                        + (app.nSolarCellRows - 1) * app.solarCellGap ...
                        + 2 * app.solarCellGap + 2 * app.frameEWidth;
                    dz = app.frameThickness;
                    dimensions = [dx, dy, dz];
                     
                    % get cuboid surface data 
                    [x, y, z] = cuboidData(app, point, dimensions);
        
                    % plot the cuboid
                    SurfPlot(app, x, y, z, app.frameColor);


                    % front bottom left point on the rear's last e-tooth 
                    % e-width-right cuboid surface
                    px = app.nSolarCellCols * app.solarCellWidth ...
                        + (app.nSolarCellCols - 1) * app.solarCellGap ...
                        + app.solarCellGap;
                    py = -app.solarCellGap-app.frameEWidth;
                    pz = zOffset + app.frameLowerEHeight;
                    point = [px, py, pz];
        
                    % dimensions of the surface in each direction from the position
                    % point
                    dx = app.frameEWidth;
                    dy = (app.nSolarCellRows * app.solarCellHeight) ...
                        + (app.nSolarCellRows - 1) * app.solarCellGap ...
                        + 2 * app.solarCellGap + 2 * app.frameEWidth;
                    dz = app.frameThickness;
                    dimensions = [dx, dy, dz];
        
                    % get cuboid surface data 
                    [x, y, z] = cuboidData(app, point, dimensions);
        
                    % plot the cuboid
                    SurfPlot(app, x, y, z, app.frameColor);


                    % front bottom left point on the right cuboid frame wall
                    px = (app.nSolarCellCols * app.solarCellWidth) ...
                        + (app.nSolarCellCols - 1) * app.solarCellGap ...
                        + app.solarCellGap + app.frameEWidth;
                    py = -app.solarCellGap-app.frameEWidth-app.frameThicknessAlongXY;
                    pz = -app.frameThickness;
                    point = [px, py, pz];
        
                    % dimensions of the surface in each direction from the position
                    % point
                    dx = app.frameThicknessAlongXY;
                    dy = (app.nSolarCellRows * app.solarCellHeight) ...
                        + (app.nSolarCellRows - 1) * app.solarCellGap ...
                        + 2 * app.solarCellGap + 2 * app.frameThicknessAlongXY ...
                        + 2 * app.frameEWidth;
                    dz = zOffset + 2 * app.frameThickness + app.frameLowerEHeight;
                    dimensions = [dx, dy, dz];
        
                    % get cuboid surface data 
                    [x, y, z] = cuboidData(app, point, dimensions);
        
                    % plot the cuboid
                    SurfPlot(app, x, y, z, app.frameColor);
                end
    
    
                if app.viewTopFrame

                    % front bottom left point on the top cuboid frame wall
                    px = -app.solarCellGap-app.frameEWidth;
                    py = -app.solarCellGap-app.frameEWidth-app.frameThicknessAlongXY;
                    pz = -app.frameThickness;
                    point = [px, py, pz];
        
                    % dimensions of the surface in each direction from the position
                    % point
                    dx = (app.nSolarCellCols * app.solarCellWidth)...
                        + (app.nSolarCellCols - 1) * app.solarCellGap ...
                        + 2 * app.solarCellGap + 2 * app.frameEWidth;
                    dy = app.frameThicknessAlongXY;
                    dz = zOffset + 2 * app.frameThickness + app.frameLowerEHeight;
                    dimensions = [dx, dy, dz];
        
                    % get cuboid surface data 
                    [x, y, z] = cuboidData(app, point, dimensions);
        
                    % plot the cuboid
                    SurfPlot(app, x, y, z, app.frameColor);


                    % front bottom left point on the top front e-tooth
                    px = -app.solarCellGap;
                    py = -app.solarCellGap-app.frameEWidth;
                    pz = -app.frameThickness;
                    point = [px, py, pz];
        
                    % dimensions of the surface in each direction from the position
                    % point
                    dx = (app.nSolarCellCols * app.solarCellWidth) ...
                        + (app.nSolarCellCols - 1) * app.solarCellGap ...
                        + 2 * app.solarCellGap;
                    dy = app.frameEWidth;
                    dz = app.frameThickness;
                    dimensions = [dx, dy, dz];
        
                     % get cuboid surface data 
                    [x, y, z] = cuboidData(app, point, dimensions);
        
                    % plot the cuboid
                    SurfPlot(app, x, y, z, app.frameColor);


                    % front bottom left point on the top rear e-tooth
                    px = -app.solarCellGap;
                    py = -app.solarCellGap-app.frameEWidth;
                    pz = zOffset;
                    point = [px, py, pz];
        
                    % dimensions of the surface in each direction from the position
                    % point
                    dx = (app.nSolarCellCols * app.solarCellWidth) ...
                        + (app.nSolarCellCols - 1) * app.solarCellGap ...
                        + 2 * app.solarCellGap;
                    dy = app.frameEWidth;
                    dz = app.frameThickness;
                    dimensions = [dx, dy, dz];
        
                    % get cuboid surface data 
                    [x, y, z] = cuboidData(app, point, dimensions);
        
                    % plot the cuboid
                    SurfPlot(app, x, y, z, app.frameColor);


                    % front bottom left point on the top rear's last e-tooth
                    px = -app.solarCellGap;
                    py = -app.solarCellGap-app.frameEWidth;
                    pz = zOffset + app.frameLowerEHeight;
                    point = [px, py, pz];
        
                    % dimensions of the surface in each direction from the position
                    % point
                    dx = (app.nSolarCellCols * app.solarCellWidth) ...
                        + (app.nSolarCellCols - 1) * app.solarCellGap ...
                        + 2 * app.solarCellGap;
                    dy = app.frameEWidth;
                    dz = app.frameThickness;
                    dimensions = [dx, dy, dz];
        
                    % get cuboid surface data 
                    [x, y, z] = cuboidData(app, point, dimensions);
        
                    % plot the cuboid
                    SurfPlot(app, x, y, z, app.frameColor);
                end

                if app.viewBottomFrame

                    % front bottom left point on the bottom cuboid frame wall
                    px = -app.solarCellGap-app.frameEWidth;
                    py = (app.nSolarCellRows * app.solarCellHeight) ...
                        + (app.nSolarCellRows - 1) * app.solarCellGap ...
                        + app.solarCellGap + app.frameEWidth;
                    pz = -app.frameThickness;
                    point = [px, py, pz];
        
                    % dimensions of the surface in each direction from the position
                    % point
                    dx = (app.nSolarCellCols * app.solarCellWidth)...
                        + (app.nSolarCellCols - 1) * app.solarCellGap ...
                        + 2 * app.solarCellGap + 2 * app.frameEWidth;
                    dy = app.frameThicknessAlongXY;
                    dz = zOffset + 2 * app.frameThickness + app.frameLowerEHeight;
                    dimensions = [dx, dy, dz];
        
                    % get cuboid surface data 
                    [x, y, z] = cuboidData(app, point, dimensions);
        
                    % plot the cuboid
                    SurfPlot(app, x, y, z, app.frameColor);


                    % front bottom left point on the bottom front e-tooth
                    px = -app.solarCellGap;
                    py = (app.nSolarCellRows * app.solarCellHeight) ...
                        + (app.nSolarCellRows - 1) * app.solarCellGap ...
                        + app.solarCellGap;
                    pz = -app.frameThickness;
                    point = [px, py, pz];
        
                    % dimensions of the surface in each direction from the position
                    % point
                    dx = (app.nSolarCellCols * app.solarCellWidth) ...
                        + (app.nSolarCellCols - 1) * app.solarCellGap ...
                        + 2 * app.solarCellGap;
                    dy = app.frameEWidth;
                    dz = app.frameThickness;
                    dimensions = [dx, dy, dz];
        
                     % get cuboid surface data 
                    [x, y, z] = cuboidData(app, point, dimensions);
        
                    % plot the cuboid
                    SurfPlot(app, x, y, z, app.frameColor);


                    % front bottom left point on the bottom rear e-tooth
                    px = -app.solarCellGap;
                    py = (app.nSolarCellRows * app.solarCellHeight) ...
                        + (app.nSolarCellRows - 1) * app.solarCellGap ...
                        + app.solarCellGap;
                    pz = zOffset;
                    point = [px, py, pz];
        
                    % dimensions of the surface in each direction from the position
                    % point
                    dx = (app.nSolarCellCols * app.solarCellWidth) ...
                        + (app.nSolarCellCols - 1) * app.solarCellGap ...
                        + 2 * app.solarCellGap;
                    dy = app.frameEWidth;
                    dz = app.frameThickness;
                    dimensions = [dx, dy, dz];
        
                     % get cuboid surface data 
                    [x, y, z] = cuboidData(app, point, dimensions);
        
                    % plot the cuboid
                    SurfPlot(app, x, y, z, app.frameColor);


                    % front bottom left point on the bottom rear's last e-tooth
                    px = -app.solarCellGap;
                    py = (app.nSolarCellRows * app.solarCellHeight) ...
                        + (app.nSolarCellRows - 1) * app.solarCellGap ...
                        + app.solarCellGap;
                    pz = zOffset + app.frameLowerEHeight;
                    point = [px, py, pz];
        
                    % dimensions of the surface in each direction from the position
                    % point
                    dx = (app.nSolarCellCols * app.solarCellWidth) ...
                        + (app.nSolarCellCols - 1) * app.solarCellGap ...
                        + 2 * app.solarCellGap;
                    dy = app.frameEWidth;
                    dz = app.frameThickness;
                    dimensions = [dx, dy, dz];
        
                     % get cuboid surface data 
                    [x, y, z] = cuboidData(app, point, dimensions);
        
                    % plot the cuboid
                    SurfPlot(app, x, y, z, app.frameColor);
                end
             end
         end

         function PlotJunctionBox(app, zOffset)
            if app.viewJunctionBox
                baseSolarXLen = (app.nSolarCellCols * app.solarCellWidth) ...
                    + (app.nSolarCellCols - 1) * app.solarCellGap ...
                    + 2 * app.solarCellGap;
                baseSolarYLen = (app.nSolarCellRows * app.solarCellHeight) ...
                    + (app.nSolarCellRows - 1) * app.solarCellGap ...
                    + 2 * app.solarCellGap;
                
                boxSize = [app.junctionBoxWidth, app.junctionBoxHeight, ...
                    app.junctionBoxThickness];
                px = (baseSolarXLen - boxSize(1)) / 2;
                py = (baseSolarYLen - boxSize(2));
                pz = zOffset;
                point = [px, py, pz];
                [x, y, z] = cuboidData(app, point, boxSize);
                % plot the cuboid
                SurfPlot(app, x, y, z, app.junctionBoxColor);
            end
        end
        
       
         function SurfPlot(app, x, y, z, color, alpha)
            if nargin < 6
                alpha = 1;  % Default value for alpha
            end
            surf(app.UIAxes, x, y, z, 'FaceColor', color, 'FaceAlpha', alpha, 'EdgeColor', 'none');
         end

        function [X, Y, Z] = cuboidData(~, position, dims)
            x0 = position(1); y0 = position(2); z0 = position(3);
            dx = dims(1); dy = dims(2); dz = dims(3);
        
            X = [0 1 1 0 0 1 1 0]*dx + x0;
            Y = [0 0 1 1 0 0 1 1]*dy + y0;
            Z = [0 0 0 0 1 1 1 1]*dz + z0;
        
            faces = [...
                1 2 3 4;  % bottom
                5 6 7 8;  % top
                1 2 6 5;  % front
                2 3 7 6;  % right
                3 4 8 7;  % back
                4 1 5 8]; % left
            
            X = reshape(X(faces'), 4, 6);
            Y = reshape(Y(faces'), 4, 6);
            Z = reshape(Z(faces'), 4, 6);
        end
        
        function initPlot(app)
            cla(app.UIAxes);
            % axis(app.UIAxes, 'equal');
            pbaspect(app.UIAxes, str2num(app.x_y_z_aspect));
            SelectView(app, app.viewType);
            app.UIAxes.Color = [0.5 0.5 0.5];
            grid(app.UIAxes, 'on');
            xlabel(app.UIAxes, 'X (m)');
            ylabel(app.UIAxes, 'Y (m)');
            zlabel(app.UIAxes, 'Z (cm)'); 
            xlim(app.UIAxes, [-1.5 * (app.solarCellGap + app.frameEWidth ...
                + app.frameThickness), (1.5 * (app.solarCellGap ...
                + app.frameEWidth + app.frameThickness) ...
                + (app.nSolarCellCols * app.solarCellWidth)...
                + (app.nSolarCellCols -1) * app.solarCellGap)]);
            ylim(app.UIAxes, [-1.5 * (app.solarCellGap + app.frameEWidth ...
                + app.frameThickness), (1.5 * (app.solarCellGap ...
                + app.frameEWidth + app.frameThickness) ...
                + (app.nSolarCellRows * app.solarCellHeight)...
                + (app.nSolarCellRows -1) * app.solarCellGap)]);
            zlimit = (5 * app.frameThickness + 2 * app.glassCoverThickness ...
                + app.frontEncapsulantThickness + app.solarCellThickness ...
                + app.rearEncapsulantThickness + app.backSheetThickness ...
                + app.frameLowerEHeight + app.junctionBoxThickness ...
                + 4 * app.interLayerGap);
            zlim(app.UIAxes, [-5 * app.frameThickness, zlimit]);
            hold(app.UIAxes, 'on');
        end

        function SelectView(app, viewType)
            app.viewType = viewType;
            title(app.UIAxes, '');
            switch viewType
                case "Default 3D"
                    view(app.UIAxes, 3);
                    set(app.UIAxes, 'ZDir', 'reverse');
                case "Top View"
                    view(app.UIAxes, 0, 90);
                case "Bottom View"
                    view(app.UIAxes, 0, -90);
            end
        end

        function EnableDisableFramePanel(app)
            panelChildren = app.FramePanel.Children;
            if strcmp(app.solarModuleType, "Bifacial") 
                for i = 1:numel(panelChildren)
                    comp = panelChildren(i);
                    if isprop(comp, 'Enable')
                        comp.Enable = 'off';
                    end
                end
            else
               for i = 1:numel(panelChildren)
                    comp = panelChildren(i);
                    if isprop(comp, 'Enable')
                        comp.Enable = 'on';
                    end
                end
            end
        end

        function EnableDisableBackSheetPanel(app)
            panelChildren = app.BackSheetPanel.Children;
            if strcmp(app.solarModuleType, "Bifacial") 
                for i = 1:numel(panelChildren)
                    comp = panelChildren(i);
                    if isprop(comp, 'Enable')
                        comp.Enable = 'off';
                    end
                end
            else
               for i = 1:numel(panelChildren)
                    comp = panelChildren(i);
                    if isprop(comp, 'Enable')
                        comp.Enable = 'on';
                    end
                end
            end
        end
            
        function EnableDisableSaveAndCloseFileOptionsForHome(app)
            fileMenuChildren = app.FileMenu.Children;
            closeFile = fileMenuChildren(2);
            save = fileMenuChildren(1);
            if strcmp(app.window, "Home")
                if isprop(closeFile, 'Enable')
                    closeFile.Enable = 'off';
                end
                if isprop(save, 'Enable')
                    save.Enable = 'off';
                end
            else
                if isprop(closeFile, 'Enable')
                    closeFile.Enable = 'on';
                end
                if isprop(save, 'Enable')
                    save.Enable = 'on';
                end
            end                
        end

        function EnableDisableOpenJsonFileOption(app)
            fileMenuChildren = app.FileMenu.Children;
            openJsonFile = fileMenuChildren(4);
            if strcmp(app.window, "Home")
                if isprop(openJsonFile, 'Enable')
                    openJsonFile.Enable = 'on';
                end
            else
                if isprop(openJsonFile, 'Enable')
                    openJsonFile.Enable = 'off';
                end
            end                
        end

        function config = GetConfigData(app)
            pvModuleConfig = struct("solarModuleType", app.solarModuleType, ...
                "solarCellThickness_microm", app.solarCellThickness_microm, ...
                "nSolarCellRows", app.nSolarCellRows, ...
                "nSolarCellCols", app.nSolarCellCols, ...
                "solarCellWidth_cm", app.solarCellWidth_cm, ...
                "solarCellHeight_cm", app.solarCellHeight_cm, ...
                "solarCellGap_cm", app.solarCellGap_cm, ...
                "solarCellColor", app.solarCellColor, ...
                "glassCoverThickness_mm", app.glassCoverThickness_mm, ...
                "glassColor", app.glassColor, ...
                "glassColorAlpha", app.glassColorAlpha, ...
                "backSheetThickness_mm", app.backSheetThickness_mm, ...
                "backSheetColor", app.backSheetColor, ...
                "frontEncapsulantThickness_mm", app.frontEncapsulantThickness_mm, ...
                "rearEncapsulantThickness_mm", app.rearEncapsulantThickness_mm, ...
                "frontEncapsulantType", app.frontEncapsulantType, ...
                "rearEncapsulantType", app.rearEncapsulantType, ...
                "encapsulantColor", app.encapsulantColor, ...
                "encapsulantColorAlpha", app.encapsulantColorAlpha, ...
                "frameThickness_mm", app.frameThickness_mm, ...
                "frameEWidth_mm", app.frameEWidth_mm, ...
                "frameLowerEHeight_mm", app.frameLowerEHeight_mm, ...
                "frameMaterial", app.frameMaterial, ...
                "frameColor", app.frameColor, ...
                "junctionBoxColor", app.junctionBoxColor, ...
                "junctionBoxWidth_cm", app.junctionBoxWidth_cm, ...
                "junctionBoxHeight_cm", app.junctionBoxHeight_cm, ...
                "junctionBoxThickness_mm", app.junctionBoxThickness_mm);
            config = struct("pvModuleConfig", pvModuleConfig);
        end
        
        function SetConfigData(app, config)
            if isfield(config, 'pvModuleConfig')
                % disp(config)
                app.solarModuleType = GetField(app, ...
                    config.pvModuleConfig, "solarModuleType", app.defaultSolarModuleType);
                app.solarCellThickness_microm = GetField(app, ...
                    config.pvModuleConfig, "solarCellThickness_microm", app.defaultSolarCellThickness_microm);
                app.nSolarCellRows = GetField(app, ...
                    config.pvModuleConfig, "nSolarCellRows", app.defaultNSolarCellRows);
                app.nSolarCellCols = GetField(app, ...
                    config.pvModuleConfig, "nSolarCellCols", app.defaultNSolarCellCols);
                app.solarCellWidth_cm = GetField(app, ...
                    config.pvModuleConfig, "solarCellWidth_cm", app.defaultSolarCellWidth_cm);
                app.solarCellHeight_cm = GetField(app, ...
                    config.pvModuleConfig, "solarCellHeight_cm", app.defaultSolarCellHeight_cm);
                app.solarCellGap_cm = GetField(app, ...
                    config.pvModuleConfig, "solarCellGap_cm", app.defaultSolarCellGap_cm);
                app.solarCellColor = GetField(app, ...
                    config.pvModuleConfig, "solarCellColor", app.defaultSolarCellColor);
                app.glassCoverThickness_mm = GetField(app, ...
                    config.pvModuleConfig, "glassCoverThickness_mm", app.defaultGlassCoverThickness_mm);
                app.glassColor = GetField(app, ...
                    config.pvModuleConfig, "glassColor", app.defaultGlassColor);
                app.glassColorAlpha = GetField(app, ...
                    config.pvModuleConfig, "glassColorAlpha", app.defaultGlassColorAlpha);
                app.backSheetThickness_mm = GetField(app, ...
                    config.pvModuleConfig, "backSheetThickness_mm", app.defaultBackSheetThickness_mm);
                app.backSheetColor = GetField(app, ...
                    config.pvModuleConfig, "backSheetColor", app.defaultBackSheetColor);
                app.frontEncapsulantThickness_mm = GetField(app, ...
                    config.pvModuleConfig, "frontEncapsulantThickness_mm", app.defaultFrontEncapsulantThickness_mm);
                app.rearEncapsulantThickness_mm = GetField(app, ...
                    config.pvModuleConfig, "rearEncapsulantThickness_mm", app.defaultRearEncapsulantThickness_mm);
                app.frontEncapsulantType = GetField(app, ...
                    config.pvModuleConfig, "frontEncapsulantType", app.defaultFrontEncapsulantType);
                app.rearEncapsulantType = GetField(app, ...
                    config.pvModuleConfig, "rearEncapsulantType", app.defaultRearEncapsulantType);
                app.encapsulantColor = GetField(app, ...
                    config.pvModuleConfig, "encapsulantColor", app.defaultEncapsulantColor);
                app.encapsulantColorAlpha = GetField(app, ...
                    config.pvModuleConfig, "encapsulantColorAlpha", app.defaultEncapsulantColorAlpha);
                app.frameThickness_mm = GetField(app, ...
                    config.pvModuleConfig, "frameThickness_mm", app.defaultFrameThickness_mm);
                app.frameEWidth_mm = GetField(app, ...
                    config.pvModuleConfig, "frameEWidth_mm", app.defaultFrameEWidth_mm);
                app.frameLowerEHeight_mm = GetField(app, ...
                    config.pvModuleConfig, "frameLowerEHeight_mm", app.defaultFrameLowerEHeight_mm);
                app.frameMaterial = GetField(app, ...
                    config.pvModuleConfig, "frameMaterial", app.defaultFrameMaterial);
                app.frameColor = GetField(app, ...
                    config.pvModuleConfig, "frameColor", app.defaultFrameColor);
                app.junctionBoxColor = GetField(app, ...
                    config.pvModuleConfig, "junctionBoxColor", app.defaultJunctionBoxColor);
                app.junctionBoxWidth_cm = GetField(app, ...
                    config.pvModuleConfig, "junctionBoxWidth_cm", app.defaultJunctionBoxWidth_cm);
                app.junctionBoxHeight_cm = GetField(app, ...
                    config.pvModuleConfig, "junctionBoxHeight_cm", app.defaultJunctionBoxHeight_cm);
                app.junctionBoxThickness_mm = GetField(app, ...
                    config.pvModuleConfig, "junctionBoxThickness_mm", app.defaultJunctionBoxThickness_mm);
            else
                uialert(app.UIFigure, 'Selected JSON do not contain PV module configuration', 'Error');
            end
        end
        

        function updateUI(app)
            app.RowsSpinner.Value = app.nSolarCellRows;
            app.ColumnsSpinner.Value = app.nSolarCellCols;
            app.WidthcmEditField.Value = app.solarCellWidth_cm;
            app.ThicknessmEditField.Value = app.solarCellThickness_microm;
            app.HeightcmEditField.Value = app.solarCellHeight_cm;
            app.CellGapcmEditField.Value = app.solarCellGap_cm;
            app.ColorColorPicker.Value = app.solarCellColor;
            app.TypeDropDown.Value = app.frontEncapsulantType;
            app.TypeDropDown_2.Value = app.rearEncapsulantType;
            app.ThicknessmmEditField_4.Value = app.rearEncapsulantThickness_mm;
            app.ThicknessmmEditField_2.Value = app.frontEncapsulantThickness_mm;
            app.ModuleTypeDropDown.Value = app.solarModuleType;  %%%
            app.ThicknessmmEditField_3.Value = app.glassCoverThickness_mm;
            app.ThicknessmmEditField.Value = app.frameThickness_mm;
            app.ColorColorPicker_3.Value = app.frameColor;
            app.WidthofFrameEmmEditField.Value = app.frameEWidth_mm;
            app.ColorColorPicker_2.Value = app.backSheetColor;
            app.ThicknessmmEditField_5.Value = app.backSheetThickness_mm;
            app.WidthcmEditField_2.Value = app.junctionBoxWidth_cm;
            app.ColorColorPicker_4.Value = app.junctionBoxColor;
            app.DepthmmEditField.Value = app.junctionBoxThickness_mm;
            app.HeightcmEditField_2.Value = app.junctionBoxHeight_cm;

            app.SelectViewDropDown.Value = app.viewType;
            app.SelectComponentDropDown.Value = app.selectComponent;
            app.xyzaspectDropDown.Value = app.x_y_z_aspect;
            app.InterlayerGapcmSlider.Value = app.interLayerGap;

            EnableDisableFramePanel(app);
            EnableDisableBackSheetPanel(app);
            EnableDisableSaveAndCloseFileOptionsForHome(app);
            EnableDisableOpenJsonFileOption(app);
        end


        function LoadConfigFile(app)
            % Let user choose a JSON file
            [file, path] = uigetfile('*.json', 'Select Configuration File');
            if isequal(file, 0)
                return; % User cancelled
            end
           
            try
                % Read and decode JSON file
                fid = fopen(fullfile(path, file), 'r');
                raw = fread(fid, inf, 'char');
                fclose(fid);
                jsonText = char(raw');
            catch
                uialert(app.UIFigure, 'Error in reading the file', 'Error');
                return;
            end
            
            try
                config = jsondecode(jsonText);
            catch 
                uialert(app.UIFigure, 'Invalid JSON format', 'Error');
                return;
            end
            
            try
                SetConfigData(app, config);
            catch
                 uialert(app.UIFigure, 'Error occurred while loading the config', 'Error');
                return;
            end
            % disp(GetConfigData(app).pvModuleConfig);
            SwitchToFileMode(app, path, file);
            
            updateUI(app);
        end

        function value = GetField(~, struct, fieldName, defaultValue)
            if isfield(struct, fieldName)
                value = struct.(fieldName);
            else
                value = defaultValue;
            end
        end

        function ValidatePVModuleParameters(app)
            isValid = true;
            errorMessages = {};
            c = 0;

            % Numeric Checks
            numericChecks = {
                {'solarCellThickness_microm', 1, 200, app.defaultSolarCellThickness_microm}
                {'nSolarCellRows', 1, 100, app.defaultNSolarCellRows}
                {'nSolarCellCols', 1, 100, app.defaultNSolarCellCols}
                {'solarCellWidth_cm', 1, 100, app.defaultSolarCellWidth_cm}
                {'solarCellHeight_cm', 1, 100, app.defaultSolarCellHeight_cm}
                {'solarCellGap_cm', 0, 10, app.defaultSolarCellGap_cm}
                {'glassCoverThickness_mm', 2, 3, app.defaultGlassCoverThickness_mm}
                {'backSheetThickness_mm', 1, 10, app.defaultBackSheetThickness_mm}
                {'frontEncapsulantThickness_mm', 0.5, 1, app.defaultFrontEncapsulantThickness_mm}
                {'rearEncapsulantThickness_mm', 0.5, 1, app.defaultRearEncapsulantThickness_mm}
                {'frameThickness_mm', 1, 5, app.defaultFrameThickness_mm}
                {'frameEWidth_mm', 5, 15, app.defaultFrameEWidth_mm}
                {'frameLowerEHeight_mm', 5, 15, app.defaultFrameLowerEHeight_mm}
                {'junctionBoxWidth_cm', 1, 50, app.defaultJunctionBoxWidth_cm}
                {'junctionBoxHeight_cm', 1, 50, app.defaultJunctionBoxHeight_cm}
                {'junctionBoxThickness_mm', 10, 50, app.defaultJunctionBoxThickness_mm}
            };
            
            for i = 1:length(numericChecks)
                varName = numericChecks{i}{1};
                minVal = numericChecks{i}{2};
                maxVal = numericChecks{i}{3};
                value = app.(varName);
                defaultVal = numericChecks{i}{4};
                if ~isnumeric(value) || ~isscalar(value) || isnan(value) ...
                        || value < minVal || value > maxVal
                    c = c + 1;
                    errorMessages{c} = sprintf('%s must be between %g and %g.', ...
                        varName, minVal, maxVal);
                    isValid = false;
                    app.(varName) = defaultVal;
                end  
            end

            % Color Checks (RGB values in [0,1])
            colorDefaults = struct( ...
                            'solarCellColor', app.defaultSolarCellColor, ...
                            'glassColor',     app.defaultGlassColor, ...
                            'backSheetColor', app.defaultBackSheetColor, ...
                            'encapsulantColor', app.defaultEncapsulantColor, ...
                            'frameColor',     app.defaultFrameColor, ...
                            'junctionBoxColor', app.defaultJunctionBoxColor);
            colorFields = fieldnames(colorDefaults);
            for i = 1:length(colorFields)
                field = colorFields{i};
                value = app.(field);
                if ~isnumeric(value) || numel(value) ~= 3 || ...
                        any(value < 0) || any(value > 1)
                    c = c + 1;
                    errorMessages{c} = sprintf('%s Invalid RGB vector', ...
                        colorFields{i});
                    isValid = false;
                    app.(field) = colorDefaults.(field);
                end
            end

            % Alpha (transparency) checks
            if isnan(app.glassColorAlpha) || ~isscalar(app.glassColorAlpha) ...
                    || app.glassColorAlpha < 0 || app.glassColorAlpha > 1
                c = c + 1;
                errorMessages{c} = 'Invalid glassColorAlpha';
                isValid = false;
                app.glassColorAlpha = app.defaultGlassColorAlpha;
            end
            if isnan(app.encapsulantColorAlpha) || ~isscalar(app.encapsulantColorAlpha) ...
                    || app.encapsulantColorAlpha < 0 || app.encapsulantColorAlpha > 1
                c = c + 1;
                errorMessages{c} = 'Invalid encapsulantColorAlpha';
                isValid = false;
                app.encapsulantColorAlpha = app.defaultEncapsulantColorAlpha;
            end

            % Solar Module Type check
            validModuleTypes = ["Monofacial", "Bifacial"];
            if (~ischar(app.solarModuleType) && ~isstring(app.solarModuleType)) ...
                    || ~any(strcmp(app.solarModuleType, validModuleTypes))
                c = c + 1;
                errorMessages{c} = 'solarModuleType must be "Monofacial" or "Bifacial".';
                isValid = false;
                app.solarModuleType = app.defaultSolarModuleType;
            end
            
            % Encapsulant Type check
            validEncapsulants = ["EVA", "POE"];
            if (~ischar(app.frontEncapsulantType) && ~isstring(app.frontEncapsulantType)) ...
                    || ~any(strcmp(app.frontEncapsulantType, validEncapsulants))
                c = c + 1;
                errorMessages{c} = 'frontEncapsulantType must be EVA or POE.';
                isValid = false;
                app.frontEncapsulantType = app.defaultFrontEncapsulantType;
            end

            if (~ischar(app.rearEncapsulantType) && ~isstring(app.rearEncapsulantType)) ...
                    || ~any(strcmp(app.rearEncapsulantType, validEncapsulants))
                c = c + 1;
                errorMessages{c} = 'rearEncapsulantType must be EVA or POE.';
                isValid = false;
                app.rearEncapsulantType = app.defaultRearEncapsulantType;
            end
        
            if ~ischar(app.frameMaterial) && ~isstring(app.frameMaterial)
                c = c + 1;
                errorMessages{c} = 'frameMaterial must be a string.';
                isValid = false;
                app.frameMaterial = app.defaultFrameMaterial;
            end
        
            % Show error messages if invalid
            if ~isValid
                c = c+1;
                errorMessages{c} = 'Default values will be used for invalid inputs';
                msg = strjoin(errorMessages, '\n');
                uialert(app.UIFigure, sprintf(msg), 'Invalid Parameters');
            end
            updateUI(app);
        end            

        
        function changed = HasJsonChanged(app)
            currentConfig = GetConfigData(app);
            try
                % Read and decode the current JSON file content
                fid = fopen(fullfile(app.path, app.fileName), 'r');
                if fid == -1
                    error('Cannot open file.');
                end
                raw = fread(fid, inf, 'char');
                fclose(fid);
                originalConfig = jsondecode(char(raw'));
        
                % Compare structures
                
                changed = ~isequal(originalConfig, currentConfig);
            catch ME
                disp(ME.message);
                changed = true; % Assume changed if any error occurs
            end
        end


        function SaveConfigFile(app, config)
             try
                % Convert to JSON string
                jsonText = jsonencode(config, "PrettyPrint", true);
                
                % Write to file (overwrite if exists)
                fid = fopen(fullfile(app.path, app.fileName), 'w');
                if fid == -1
                    error('File could not be opened.');
                end
                fwrite(fid, jsonText, 'char');
                fclose(fid);
                
                % show success message
                uialert(app.UIFigure, 'Configuration saved successfully.', 'Success');
            catch ME
                uialert(app.UIFigure, sprintf('Failed to save file:\n%s', ME.message), 'Error');
             end
        end

        function SwitchToHomeMode(app)
            app.window = "Home";
            app.HomeTab.Title = "Home";

            app.fileName = "";
            app.path = "";
        end

        function SwitchToFileMode(app, path, file)
            app.HomeTab.Title = file;
            app.window = "file";
            app.fileName = file;
            app.path = path;
        end


        function ResetToDefaults(app)

            app.solarModuleType = app.defaultSolarModuleType;
            app.solarCellThickness_microm = app.defaultSolarCellThickness_microm;
            app.nSolarCellRows = app.defaultNSolarCellRows;
            app.nSolarCellCols = app.defaultNSolarCellCols;
            app.solarCellWidth_cm = app.defaultSolarCellWidth_cm;
            app.solarCellHeight_cm = app.defaultSolarCellHeight_cm;
            app.solarCellGap_cm = app.defaultSolarCellGap_cm;
            app.solarCellColor = app.defaultSolarCellColor;
            app.glassCoverThickness_mm = app.defaultGlassCoverThickness_mm;
            app.glassColor = app.defaultGlassColor;
            app.glassColorAlpha = app.defaultGlassColorAlpha;
            app.backSheetThickness_mm = app.defaultBackSheetThickness_mm;
            app.backSheetColor = app.defaultBackSheetColor;
            app.frontEncapsulantThickness_mm = app.defaultFrontEncapsulantThickness_mm;
            app.rearEncapsulantThickness_mm = app.defaultRearEncapsulantThickness_mm;
            app.frontEncapsulantType = app.defaultFrontEncapsulantType;
            app.rearEncapsulantType = app.defaultRearEncapsulantType;
            app.encapsulantColor = app.defaultEncapsulantColor;
            app.encapsulantColorAlpha = app.defaultEncapsulantColorAlpha;
            app.frameThickness_mm = app.defaultFrameThickness_mm;
            app.frameEWidth_mm = app.defaultFrameEWidth_mm;
            app.frameLowerEHeight_mm = app.defaultFrameLowerEHeight_mm;
            app.frameMaterial = app.defaultFrameMaterial;
            app.frameColor = app.defaultFrameColor;
            app.junctionBoxColor = app.defaultJunctionBoxColor;
            app.junctionBoxWidth_cm = app.defaultJunctionBoxWidth_cm;
            app.junctionBoxHeight_cm = app.defaultJunctionBoxHeight_cm;
            app.junctionBoxThickness_mm = app.defaultJunctionBoxThickness_mm;
            app.viewType = app.defaultViewType;
            app.interLayerGap = app.defaultInterLayerGap;
            app.viewFrame = app.defaultViewFrame;
            app.viewGlass = app.defaultViewGlass;
            app.viewSolarCells = app.defaultViewSolarCells;
            app.viewEncapsulant = app.defaultViewEncapsulant;
            app.viewBackSheet = app.defaultViewBackSheet;
            app.viewJunctionBox = app.defaultViewJunctionBox;

            app.viewLeftFrame = app.defaultViewLeftFrame;
            app.viewRightFrame = app.defaultViewRightFrame;
            app.viewTopFrame = app.defaultViewTopFrame;
            app.viewBottomFrame = app.defaultViewBottomFrame;

            app.x_y_z_aspect = app.default_x_y_z_aspect;
            app.selectComponent = app.defaultSelectComponent;
            % app.window = app.defaultWindow;
            % app.fileName = app.defaultFileName;
            % app.path = app.defaultPath;
            

            updateUI(app);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            EnableDisableSaveAndCloseFileOptionsForHome(app);
            PlotPVModule(app);
        end

        % Value changed function: RowsSpinner
        function RowsSpinnerValueChanged(app, event)
            value = app.RowsSpinner.Value;
            app.nSolarCellRows = value;
            PlotPVModule(app);
        end

        % Value changed function: ColumnsSpinner
        function ColumnsSpinnerValueChanged(app, event)
            value = app.ColumnsSpinner.Value;
            app.nSolarCellCols = value;
            PlotPVModule(app);
        end

        % Value changed function: WidthcmEditField
        function WidthcmEditFieldValueChanged(app, event)
            value = app.WidthcmEditField.Value;
            app.solarCellWidth_cm = value;
            PlotPVModule(app);
        end

        % Value changed function: HeightcmEditField
        function HeightcmEditFieldValueChanged(app, event)
            value = app.HeightcmEditField.Value;
            app.solarCellHeight_cm = value;
            PlotPVModule(app);
        end

        % Value changed function: CellGapcmEditField
        function CellGapcmEditFieldValueChanged(app, event)
            value = app.CellGapcmEditField.Value;
            app.solarCellGap_cm = value;
            PlotPVModule(app);
        end

        % Value changed function: ThicknessmEditField
        function ThicknessmEditFieldValueChanged(app, event)
            value = app.ThicknessmEditField.Value;
            app.solarCellThickness_microm = value;
            PlotPVModule(app);
        end

        % Callback function: ColorColorPicker
        function ColorColorPickerValueChanged(app, event)
            value = app.ColorColorPicker.Value;
            app.solarCellColor = value;
            PlotPVModule(app);
        end

        % Value changed function: ThicknessmmEditField
        function ThicknessmmEditFieldValueChanged(app, event)
            value = app.ThicknessmmEditField.Value;
            app.frameThickness_mm = value;
            PlotPVModule(app);
        end

        % Callback function: ColorColorPicker_3
        function ColorColorPicker_3ValueChanged(app, event)
            value = app.ColorColorPicker_3.Value;
            app.frameColor = value;
            PlotPVModule(app);
        end

        % Value changed function: WidthofFrameEmmEditField
        function WidthofFrameEmmEditFieldValueChanged(app, event)
            value = app.WidthofFrameEmmEditField.Value;
            app.frameEWidth_mm = value;
            app.frameLowerEHeight_mm = value;
            PlotPVModule(app);
        end

        % Value changed function: ThicknessmmEditField_5
        function ThicknessmmEditField_5ValueChanged(app, event)
            value = app.ThicknessmmEditField_5.Value;
            app.backSheetThickness_mm = value;
            PlotPVModule(app);
        end

        % Callback function: ColorColorPicker_2
        function ColorColorPicker_2ValueChanged(app, event)
            value = app.ColorColorPicker_2.Value;
            app.backSheetColor = value;
            PlotPVModule(app);
        end

        % Value changed function: SelectViewDropDown
        function SelectViewDropDownValueChanged(app, event)
            value = app.SelectViewDropDown.Value;
            app.viewType = value;
            SelectView(app, value)
        end

        % Value changed function: ModuleTypeDropDown
        function ModuleTypeDropDownValueChanged(app, event)
            value = app.ModuleTypeDropDown.Value;
            app.solarModuleType = value;
            EnableDisableFramePanel(app);
            EnableDisableBackSheetPanel(app);
            PlotPVModule(app);
        end

        % Value changed function: ThicknessmmEditField_2
        function ThicknessmmEditField_2ValueChanged(app, event)
            value = app.ThicknessmmEditField_2.Value;
            app.frontEncapsulantThickness_mm = value;
            PlotPVModule(app);
        end

        % Value changed function: ThicknessmmEditField_4
        function ThicknessmmEditField_4ValueChanged(app, event)
            value = app.ThicknessmmEditField_4.Value;
            app.rearEncapsulantThickness_mm = value;
            PlotPVModule(app);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            app.frontEncapsulantType = value;
        end

        % Value changed function: TypeDropDown_2
        function TypeDropDown_2ValueChanged(app, event)
            value = app.TypeDropDown_2.Value;
            app.rearEncapsulantType = value;
        end

        % Value changed function: ThicknessmmEditField_3
        function ThicknessmmEditField_3ValueChanged(app, event)
            value = app.ThicknessmmEditField_3.Value;
            app.glassCoverThickness_mm = value;
            PlotPVModule(app);
        end

        % Value changed function: WidthcmEditField_2
        function WidthcmEditField_2ValueChanged(app, event)
            value = app.WidthcmEditField_2.Value;
            app.junctionBoxWidth_cm = value;
            PlotPVModule(app);
        end

        % Value changed function: DepthmmEditField
        function DepthmmEditFieldValueChanged(app, event)
            value = app.DepthmmEditField.Value;
            app.junctionBoxThickness_mm = value;
            
            PlotPVModule(app);
        end

        % Value changed function: HeightcmEditField_2
        function HeightcmEditField_2ValueChanged(app, event)
            value = app.HeightcmEditField_2.Value;
            app.junctionBoxHeight_cm = value;
            PlotPVModule(app);
        end

        % Callback function: ColorColorPicker_4
        function ColorColorPicker_4ValueChanged(app, event)
            value = app.ColorColorPicker_4.Value;
            app.junctionBoxColor = value;
            PlotPVModule(app);
        end

        % Value changed function: InterlayerGapcmSlider
        function InterlayerGapcmSliderValueChanged(app, event)
            value = app.InterlayerGapcmSlider.Value;
            app.interLayerGap = value;
            PlotPVModule(app);
        end

        % Value changed function: SelectComponentDropDown
        function SelectComponentDropDownValueChanged(app, event)
            value = app.SelectComponentDropDown.Value;
            app.selectComponent = value;
            switch value
                case "All"
                    app.viewFrame = true;
                    app.viewGlass = true;
                    app.viewSolarCells = true;
                    app.viewEncapsulant = true;
                    app.viewBackSheet = true;
                    app.viewJunctionBox = true;

                    app.viewLeftFrame = true;
                    app.viewRightFrame = true;
                    app.viewTopFrame = true;
                    app.viewBottomFrame = true;
                case "All except frame"
                    app.viewFrame = false;
                    app.viewGlass = true;
                    app.viewSolarCells = true;
                    app.viewEncapsulant = true;
                    app.viewBackSheet = true;
                    app.viewJunctionBox = true;
                case "Glass cover"
                    app.viewFrame = false;
                    app.viewGlass = true;
                    app.viewSolarCells = false;
                    app.viewEncapsulant = false;
                    app.viewBackSheet = false;
                    app.viewJunctionBox = false;
                case "Encapsulant"
                    app.viewFrame = false;
                    app.viewGlass = false;
                    app.viewSolarCells = false;
                    app.viewEncapsulant = true;
                    app.viewBackSheet = false;
                    app.viewJunctionBox = false;
                case "Solar cells"
                    app.viewFrame = false;
                    app.viewGlass = false;
                    app.viewSolarCells = true;
                    app.viewEncapsulant = false;
                    app.viewBackSheet = false;
                    app.viewJunctionBox = false;
                case "Back sheet"
                    app.viewFrame = false;
                    app.viewGlass = false;
                    app.viewSolarCells = false;
                    app.viewEncapsulant = false;
                    app.viewBackSheet = true;
                    app.viewJunctionBox = false;
                case "Junction box"
                    app.viewFrame = false;
                    app.viewGlass = false;
                    app.viewSolarCells = false;
                    app.viewEncapsulant = false;
                    app.viewBackSheet = false;
                    app.viewJunctionBox = true;
                case "Frame"
                    app.viewFrame = true;
                    app.viewGlass = false;
                    app.viewSolarCells = false;
                    app.viewEncapsulant = false;
                    app.viewBackSheet = false;
                    app.viewJunctionBox = false;

                    app.viewLeftFrame = true;
                    app.viewRightFrame = true;
                    app.viewTopFrame = true;
                    app.viewBottomFrame = true;
                case "Left frame"
                    app.viewFrame = true;
                    app.viewGlass = false;
                    app.viewSolarCells = false;
                    app.viewEncapsulant = false;
                    app.viewBackSheet = false;
                    app.viewJunctionBox = false;

                    app.viewLeftFrame = true;
                    app.viewRightFrame = false;
                    app.viewTopFrame = false;
                    app.viewBottomFrame = false;
                case "Right frame"
                    app.viewFrame = true;
                    app.viewGlass = false;
                    app.viewSolarCells = false;
                    app.viewEncapsulant = false;
                    app.viewBackSheet = false;
                    app.viewJunctionBox = false;

                    app.viewLeftFrame = false;
                    app.viewRightFrame = true;
                    app.viewTopFrame = false;
                    app.viewBottomFrame = false;
                case "Top frame"
                    app.viewFrame = true;
                    app.viewGlass = false;
                    app.viewSolarCells = false;
                    app.viewEncapsulant = false;
                    app.viewBackSheet = false;
                    app.viewJunctionBox = false;

                    app.viewLeftFrame = false;
                    app.viewRightFrame = false;
                    app.viewTopFrame = true;
                    app.viewBottomFrame = false;
                case "Bottom frame"
                    app.viewFrame = true;
                    app.viewGlass = false;
                    app.viewSolarCells = false;
                    app.viewEncapsulant = false;
                    app.viewBackSheet = false;
                    app.viewJunctionBox = false;

                    app.viewLeftFrame = false;
                    app.viewRightFrame = false;
                    app.viewTopFrame = false;
                    app.viewBottomFrame = true;
            end
            PlotPVModule(app);
        end

        % Menu selected function: OpenjsonfileMenu
        function OpenjsonfileMenuSelected(app, event)
            LoadConfigFile(app);
            PlotPVModule(app);
        end

        % Value changed function: xyzaspectDropDown
        function xyzaspectDropDownValueChanged(app, event)
            value = app.xyzaspectDropDown.Value;
            app.x_y_z_aspect = value;
            PlotPVModule(app);
        end

        % Menu selected function: SaveconfigasMenu
        function SaveconfigasMenuSelected(app, event)
            config = GetConfigData(app);
            try 
                jsonText = jsonencode(config, "PrettyPrint", true);
    
                [file, path] = uiputfile('*.json', 'Configuration');
                if isequal(file, 0)
                    return;
                end
            
                fid = fopen(fullfile(path, file), 'w');
                fwrite(fid, jsonText, 'char');
                fclose(fid);
            catch
                uialert(app.UIFigure, 'Error occurred while downloading the config', 'Error');
            end
        end

        % Menu selected function: ClosejsonfileMenu
        function ClosejsonfileMenuSelected(app, event)
            changed = HasJsonChanged(app);
            if changed
                selection = uiconfirm(app.UIFigure, ...
                    'The configuration has been modified. Do you want to save changes before closing?', ...
                    'Unsaved Changes', ...
                    'Options', {'Save and Close', 'Close Without Saving', 'Cancel'}, ...
                    'DefaultOption', 'Save and Close', ...
                    'CancelOption', 'Cancel');
                
                switch selection
                    case 'Save and Close'
                        config = GetConfigData(app); 
                        SaveConfigFile(app, config);
                    case 'Close Without Saving'
                        % Do nothing for saving
                    case 'Cancel'
                        % Do nothing; user canceled
                        return;
                end
            end
            SwitchToHomeMode(app);
            
            ResetToDefaults(app);
            uialert(app.UIFigure, 'Done. File closed!', 'Success');
            PlotPVModule(app);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            config = GetConfigData(app); 
            SaveConfigFile(app, config);
        end

        % Button pushed function: ResettodefaultvaluesButton
        function ResettodefaultvaluesButtonPushed(app, event)
            ResetToDefaults(app);
            PlotPVModule(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenjsonfileMenu = uimenu(app.FileMenu);
            app.OpenjsonfileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenjsonfileMenuSelected, true);
            app.OpenjsonfileMenu.Text = 'Open json file';
            app.SaveconfigasMenu = uimenu(app.FileMenu);
            app.SaveconfigasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveconfigasMenuSelected, true);
            app.SaveconfigasMenu.Text = 'Save config as';
            app.ClosejsonfileMenu = uimenu(app.FileMenu);
            app.ClosejsonfileMenu.MenuSelectedFcn = createCallbackFcn(app, @ClosejsonfileMenuSelected, true);
            app.ClosejsonfileMenu.Text = 'Close json file';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AuthorLoganathanAvithiNagarajanMenu = uimenu(app.HelpMenu);
            app.AuthorLoganathanAvithiNagarajanMenu.Text = 'Author   : Loganathan Avithi Nagarajan';
            app.Contactanloganathan20gmailcomMenu = uimenu(app.HelpMenu);
            app.Contactanloganathan20gmailcomMenu.Text = 'Contact : anloganathan20@gmail.com';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.UIAxes = uiaxes(app.HomeTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ModuleConfigurationPanel = uipanel(app.HomeTab);
            app.ModuleConfigurationPanel.Title = 'Module Configuration';
            app.SolarCellsPanel = uipanel(app.ModuleConfigurationPanel);
            app.SolarCellsPanel.Title = 'Solar Cells';
            app.RowsSpinnerLabel = uilabel(app.SolarCellsPanel);
            app.RowsSpinnerLabel.Text = 'Rows';
            app.RowsSpinner = uispinner(app.SolarCellsPanel);
            app.RowsSpinner.ValueChangedFcn = createCallbackFcn(app, @RowsSpinnerValueChanged, true);
            app.RowsSpinner.Value = 15;
            app.ColumnsSpinnerLabel = uilabel(app.SolarCellsPanel);
            app.ColumnsSpinnerLabel.Text = 'Columns';
            app.ColumnsSpinner = uispinner(app.SolarCellsPanel);
            app.ColumnsSpinner.ValueChangedFcn = createCallbackFcn(app, @ColumnsSpinnerValueChanged, true);
            app.ColumnsSpinner.Value = 10;
            app.WidthcmLabel = uilabel(app.SolarCellsPanel);
            app.WidthcmLabel.Text = 'Width (cm)';
            app.WidthcmEditField = uieditfield(app.SolarCellsPanel, 'numeric');
            app.WidthcmEditField.ValueChangedFcn = createCallbackFcn(app, @WidthcmEditFieldValueChanged, true);
            app.WidthcmEditField.Value = 15;
            app.ThicknessmLabel = uilabel(app.SolarCellsPanel);
            app.ThicknessmLabel.Text = 'Thickness(µm)';
            app.ThicknessmEditField = uieditfield(app.SolarCellsPanel, 'numeric');
            app.ThicknessmEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessmEditFieldValueChanged, true);
            app.ThicknessmEditField.Value = 200;
            app.HeightcmEditFieldLabel = uilabel(app.SolarCellsPanel);
            app.HeightcmEditFieldLabel.Text = 'Height (cm)';
            app.HeightcmEditField = uieditfield(app.SolarCellsPanel, 'numeric');
            app.HeightcmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightcmEditFieldValueChanged, true);
            app.HeightcmEditField.Value = 15;
            app.CellGapcmLabel = uilabel(app.SolarCellsPanel);
            app.CellGapcmLabel.Text = 'CellGap (cm)';
            app.CellGapcmEditField = uieditfield(app.SolarCellsPanel, 'numeric');
            app.CellGapcmEditField.ValueChangedFcn = createCallbackFcn(app, @CellGapcmEditFieldValueChanged, true);
            app.CellGapcmEditField.Value = 1.5;
            app.ColorColorPickerLabel = uilabel(app.SolarCellsPanel);
            app.ColorColorPickerLabel.Text = 'Color';
            app.ColorColorPicker = uicolorpicker(app.SolarCellsPanel);
            app.ColorColorPicker.Value = [0 0.447058823529412 0.741176470588235];
            app.ColorColorPicker.ValueChangedFcn = createCallbackFcn(app, @ColorColorPickerValueChanged, true);
            app.EncapsulantPanel = uipanel(app.ModuleConfigurationPanel);
            app.EncapsulantPanel.Title = 'Encapsulant';
            app.FrontPanel = uipanel(app.EncapsulantPanel);
            app.FrontPanel.Title = 'Front';
            app.TypefrontLabel = uilabel(app.FrontPanel);
            app.TypefrontLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.FrontPanel);
            app.TypeDropDown.Items = {'EVA', 'POE'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'EVA';
            app.RearPanel = uipanel(app.EncapsulantPanel);
            app.RearPanel.Title = 'Rear';
            app.TypeDropDown_2Label = uilabel(app.RearPanel);
            app.TypeDropDown_2Label.Text = 'Type';
            app.TypeDropDown_2 = uidropdown(app.RearPanel);
            app.TypeDropDown_2.Items = {'EVA', 'POE'};
            app.TypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TypeDropDown_2ValueChanged, true);
            app.TypeDropDown_2.Value = 'EVA';
            app.ThicknessmmEditField_4Label = uilabel(app.RearPanel);
            app.ThicknessmmEditField_4Label.Text = 'Thickness (mm)';
            app.ThicknessmmEditField_4 = uieditfield(app.RearPanel, 'numeric');
            app.ThicknessmmEditField_4.ValueChangedFcn = createCallbackFcn(app, @ThicknessmmEditField_4ValueChanged, true);
            app.ThicknessmmEditField_4.Value = 0.5;
            app.ThicknessmmEditField_2Label = uilabel(app.EncapsulantPanel);
            app.ThicknessmmEditField_2Label.Text = 'Thickness (mm)';
            app.ThicknessmmEditField_2 = uieditfield(app.EncapsulantPanel, 'numeric');
            app.ThicknessmmEditField_2.ValueChangedFcn = createCallbackFcn(app, @ThicknessmmEditField_2ValueChanged, true);
            app.ThicknessmmEditField_2.Value = 0.5;
            app.GlassCoverPanel = uipanel(app.ModuleConfigurationPanel);
            app.GlassCoverPanel.Title = 'Glass Cover';
            app.ModuleTypeDropDownLabel = uilabel(app.GlassCoverPanel);
            app.ModuleTypeDropDownLabel.Text = 'Module Type';
            app.ModuleTypeDropDown = uidropdown(app.GlassCoverPanel);
            app.ModuleTypeDropDown.Items = {'Monofacial', 'Bifacial'};
            app.ModuleTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModuleTypeDropDownValueChanged, true);
            app.ModuleTypeDropDown.Value = 'Monofacial';
            app.ThicknessmmEditField_3Label = uilabel(app.GlassCoverPanel);
            app.ThicknessmmEditField_3Label.Text = 'Thickness (mm)';
            app.ThicknessmmEditField_3 = uieditfield(app.GlassCoverPanel, 'numeric');
            app.ThicknessmmEditField_3.ValueChangedFcn = createCallbackFcn(app, @ThicknessmmEditField_3ValueChanged, true);
            app.ThicknessmmEditField_3.Value = 3;
            app.FramePanel = uipanel(app.ModuleConfigurationPanel);
            app.FramePanel.Title = 'Frame';
            app.ThicknessmmEditFieldLabel = uilabel(app.FramePanel);
            app.ThicknessmmEditFieldLabel.Text = 'Thickness (mm)';
            app.ThicknessmmEditField = uieditfield(app.FramePanel, 'numeric');
            app.ThicknessmmEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessmmEditFieldValueChanged, true);
            app.ThicknessmmEditField.Value = 3;
            app.ColorColorPicker_3Label = uilabel(app.FramePanel);
            app.ColorColorPicker_3Label.Text = 'Color ';
            app.ColorColorPicker_3 = uicolorpicker(app.FramePanel);
            app.ColorColorPicker_3.Value = [0.8 0.8 0.8];
            app.ColorColorPicker_3.ValueChangedFcn = createCallbackFcn(app, @ColorColorPicker_3ValueChanged, true);
            app.WidthofFrameEmmEditFieldLabel = uilabel(app.FramePanel);
            app.WidthofFrameEmmEditFieldLabel.Text = 'Width of Frame E (mm)';
            app.WidthofFrameEmmEditField = uieditfield(app.FramePanel, 'numeric');
            app.WidthofFrameEmmEditField.ValueChangedFcn = createCallbackFcn(app, @WidthofFrameEmmEditFieldValueChanged, true);
            app.WidthofFrameEmmEditField.Value = 10;
            app.BackSheetPanel = uipanel(app.ModuleConfigurationPanel);
            app.BackSheetPanel.Title = 'Back Sheet';
            app.ColorColorPicker_2Label = uilabel(app.BackSheetPanel);
            app.ColorColorPicker_2Label.Text = 'Color ';
            app.ColorColorPicker_2 = uicolorpicker(app.BackSheetPanel);
            app.ColorColorPicker_2.Value = [1 1 1];
            app.ColorColorPicker_2.ValueChangedFcn = createCallbackFcn(app, @ColorColorPicker_2ValueChanged, true);
            app.ThicknessmmEditField_5Label = uilabel(app.BackSheetPanel);
            app.ThicknessmmEditField_5Label.Text = 'Thickness (mm)';
            app.ThicknessmmEditField_5 = uieditfield(app.BackSheetPanel, 'numeric');
            app.ThicknessmmEditField_5.ValueChangedFcn = createCallbackFcn(app, @ThicknessmmEditField_5ValueChanged, true);
            app.ThicknessmmEditField_5.Value = 5;
            app.JunctionBoxPanel = uipanel(app.ModuleConfigurationPanel);
            app.JunctionBoxPanel.Title = 'Junction Box';
            app.WidthcmEditField_2Label = uilabel(app.JunctionBoxPanel);
            app.WidthcmEditField_2Label.Text = 'Width (cm)';
            app.WidthcmEditField_2 = uieditfield(app.JunctionBoxPanel, 'numeric');
            app.WidthcmEditField_2.ValueChangedFcn = createCallbackFcn(app, @WidthcmEditField_2ValueChanged, true);
            app.WidthcmEditField_2.Value = 10;
            app.ColorColorPicker_4Label = uilabel(app.JunctionBoxPanel);
            app.ColorColorPicker_4Label.Text = 'Color ';
            app.ColorColorPicker_4 = uicolorpicker(app.JunctionBoxPanel);
            app.ColorColorPicker_4.Value = [0 0 0];
            app.ColorColorPicker_4.ValueChangedFcn = createCallbackFcn(app, @ColorColorPicker_4ValueChanged, true);
            app.DepthmmEditFieldLabel = uilabel(app.JunctionBoxPanel);
            app.DepthmmEditFieldLabel.Text = 'Depth (mm)';
            app.DepthmmEditField = uieditfield(app.JunctionBoxPanel, 'numeric');
            app.DepthmmEditField.ValueChangedFcn = createCallbackFcn(app, @DepthmmEditFieldValueChanged, true);
            app.DepthmmEditField.Value = 20;
            app.HeightcmEditField_2Label = uilabel(app.ModuleConfigurationPanel);
            app.HeightcmEditField_2Label.Text = 'Height (cm)';
            app.HeightcmEditField_2 = uieditfield(app.ModuleConfigurationPanel, 'numeric');
            app.HeightcmEditField_2.ValueChangedFcn = createCallbackFcn(app, @HeightcmEditField_2ValueChanged, true);
            app.HeightcmEditField_2.Value = 10;
            app.AdvancedOptionsPanel = uipanel(app.ModuleConfigurationPanel);
            app.AdvancedOptionsPanel.Title = 'Advanced Options';
            app.ResettodefaultvaluesButton = uibutton(app.AdvancedOptionsPanel, 'push');
            app.ResettodefaultvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @ResettodefaultvaluesButtonPushed, true);
            app.ResettodefaultvaluesButton.Text = 'Reset to default values';
            app.MadebyLoganathanAvithiNagarajananloganathan20gmailcomLabel = uilabel(app.AdvancedOptionsPanel);
            app.MadebyLoganathanAvithiNagarajananloganathan20gmailcomLabel.Text = 'Made by Loganathan Avithi Nagarajan, anloganathan20@gmail.com';
            app.NoteXandYaxesareinmetersmwhiletheZaxisisincentimeterscmLabel = uilabel(app.AdvancedOptionsPanel);
            app.NoteXandYaxesareinmetersmwhiletheZaxisisincentimeterscmLabel.Text = 'Note: X and Y axes are in meters (m), while the Z axis is in centimeters (cm)';
            app.SelectViewDropDownLabel = uilabel(app.HomeTab);
            app.SelectViewDropDownLabel.Text = 'Select View';
            app.SelectViewDropDown = uidropdown(app.HomeTab);
            app.SelectViewDropDown.Items = {'Default 3D', 'Top View', 'Bottom View'};
            app.SelectViewDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectViewDropDownValueChanged, true);
            app.SelectViewDropDown.Value = 'Default 3D';
            app.InterlayerGapcmSliderLabel = uilabel(app.HomeTab);
            app.InterlayerGapcmSliderLabel.Text = 'Interlayer Gap (cm)';
            app.InterlayerGapcmSlider = uislider(app.HomeTab);
            app.InterlayerGapcmSlider.Limits = [0 1];
            app.InterlayerGapcmSlider.ValueChangedFcn = createCallbackFcn(app, @InterlayerGapcmSliderValueChanged, true);
            app.SelectComponentDropDownLabel = uilabel(app.HomeTab);
            app.SelectComponentDropDownLabel.Text = 'Select Component';
            app.SelectComponentDropDown = uidropdown(app.HomeTab);
            app.SelectComponentDropDown.Items = {'All', 'All except frame', 'Glass cover', 'Encapsulant', 'Solar cells', 'Back sheet', 'Junction box', 'Frame', 'Left frame', 'Right frame', 'Top frame', 'Bottom frame'};
            app.SelectComponentDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectComponentDropDownValueChanged, true);
            app.SelectComponentDropDown.Value = 'All';
            app.xyzaspectDropDownLabel = uilabel(app.HomeTab);
            app.xyzaspectDropDownLabel.Text = 'x-y-z-aspect';
            app.xyzaspectDropDown = uidropdown(app.HomeTab);
            app.xyzaspectDropDown.Items = {'1, 1, 0.25', '1, 1, 0.01', '1, 1, 0.50', '1, 1, 0.75', '1, 1, 1'};
            app.xyzaspectDropDown.ValueChangedFcn = createCallbackFcn(app, @xyzaspectDropDownValueChanged, true);
            app.xyzaspectDropDown.Value = '1, 1, 0.25';
            app.infoTab = uitab(app.TabGroup);
            app.infoTab.Title = 'info';
            app.SolarcellsTextAreaLabel = uilabel(app.infoTab);
            app.SolarcellsTextAreaLabel.Text = 'Solar cells';
            app.SolarcellsTextArea = uitextarea(app.infoTab);
            app.SolarcellsTextArea.Value = {'The core component that converts sunlight into electricity, typically made of silicon. They are cut from a cylindrical ingot, so that the cells often have round corners and straight sides (pseudo-square). However, rectangular cells are also available in the market. The thickness of the cells is nowadays less than 200 µm.'};
            app.GlassCoverTextAreaLabel = uilabel(app.infoTab);
            app.GlassCoverTextAreaLabel.Text = 'Glass Cover';
            app.GlassCoverTextArea = uitextarea(app.infoTab);
            app.GlassCoverTextArea.Value = {'Protects the solar cells from environmental factors and provides support, while allowing sunlight to pass through. The thickness of the glass usually adapts to the type of solar module. Bifacial solar modules often have no frame and thicker glass, for providing mechanical strength to the module. Monofacial modules'; 'have a front glass and a plastic backsheet in the rear. The glass used in highperformance modules is more transparent than normal glass, but some applications color the glass by different means. Typical glass thickness is around 2-3 mm.'};
            app.BacksheetLabel = uilabel(app.infoTab);
            app.BacksheetLabel.Text = 'Backsheet';
            app.BacksheetTextArea = uitextarea(app.infoTab);
            app.BacksheetTextArea.Value = {'The rear layer that provides insulation and protection from moisture and mechanical damage. Can be white (for increased reflectivity) or black (for better aesthetics).'};
            app.EncapsulantTextAreaLabel = uilabel(app.infoTab);
            app.EncapsulantTextAreaLabel.Text = 'Encapsulant';
            app.EncapsulantTextArea = uitextarea(app.infoTab);
            app.EncapsulantTextArea.Value = {'A layer (often made of EVA or POE) that surrounds the solar cells, providing protection and ensuring durability. The lower (rear side) encapsulant normally blocks UV light to either prevent degradation of some module components, while the top (front) encapsulant is UV-transmissive. Thickness 0.5-1 mm.'};
            app.FrameTextAreaLabel = uilabel(app.infoTab);
            app.FrameTextAreaLabel.Text = 'Frame';
            app.FrameTextArea = uitextarea(app.infoTab);
            app.FrameTextArea.Value = {'Usually made of aluminum, it provides structural support and facilitates mounting.'};
            app.JunctionBoxTextAreaLabel = uilabel(app.infoTab);
            app.JunctionBoxTextAreaLabel.Text = 'Junction Box';
            app.JunctionBoxTextArea = uitextarea(app.infoTab);
            app.JunctionBoxTextArea.Value = {'Houses the electrical connections and protects them from the elements. It often includes bypass diodes to improve performance in partial shading.'};
            app.TextArea = uitextarea(app.infoTab);
            app.TextArea.Value = {'A PV (Photovoltaic) module, commonly known as a solar panel, is a device that converts sunlight into electrical energy through the photovoltaic effect. '; ''; 'The typical components are:'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PVModuleSimulatorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end